classdef Wheathers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        WindSpeedLabel            matlab.ui.control.Label
        HumidityLabel             matlab.ui.control.Label
        TemperatureCLabel         matlab.ui.control.Label
        LastFiveDaysLabel         matlab.ui.control.Label
        CurrentInformationsLabel  matlab.ui.control.Label
        GetWheaterButton          matlab.ui.control.Button
        AddButton                 matlab.ui.control.Button
        AddcityEditField          matlab.ui.control.EditField
        AddcityLabel              matlab.ui.control.Label
        SelectCityDropDown        matlab.ui.control.DropDown
        SelectCityDropDownLabel   matlab.ui.control.Label
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        api_key string = '13ac766a8b9b4e240a0fe1e3e7d19b3c'
        cities cell = {'Istanbul', 'Ankara', 'Izmir', 'Bursa', 'Adana', 'Gaziantep'}
    

    end

        methods (Access = private)
        
        function  getWheathersData(app)
            wheatherData=getWeathersForFiveDay(app.SelectCityDropDown.Value,app.api_key);
                    % Convert datetime cell array to normal datetime array
            w = cell(size(wheatherData.time));
            for i = 1:numel(wheatherData.time)
                 w{i} = datetime(wheatherData.time{i});
            end
            % Update temperature plot
            plot(app.UIAxes, [w{:}], wheatherData.temp);
            app.UIAxes.Title.String = ['Temperature (°C) For ' , app.SelectCityDropDown.Value] ;
            app.UIAxes.XLabel.String = 'Time';
            app.UIAxes.YLabel.String = 'Temperature (°C)';
            
            % Update humidity plot
            plot(app.UIAxes2, [w{:}], wheatherData.humidity);
            app.UIAxes2.Title.String = ['Humidity (%) For ', app.SelectCityDropDown.Value];
            app.UIAxes2.XLabel.String = 'Time';
            app.UIAxes2.YLabel.String = 'Humidity (%)';
            
            % Update wind speed plot
            plot(app.UIAxes3,[w{:}], wheatherData.windSpeed);
            app.UIAxes3.Title.String =[ 'Wind Speed (m/s) For ' , app.SelectCityDropDown.Value];
            app.UIAxes3.XLabel.String = 'Time';
            app.UIAxes3.YLabel.String = 'Wind Speed (m/s)';
            
            % Update current conditions
            app.TemperatureCLabel.Text = ['Current Temperature: ', num2str(wheatherData.temp(1)), ' °C'];
            app.HumidityLabel.Text = ['Current Humidity: ', num2str(wheatherData.humidity(1)), ' %'];
            app.WindSpeedLabel.Text = ['Current Wind Speed: ', num2str(wheatherData.windSpeed(1)), ' m/s'];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SelectCityDropDown.Items = app.cities;
            app.getWheathersData();
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)

        newCity = app.AddcityEditField.Value;
        if ~isempty(newCity) && ~any(strcmp(app.cities, newCity))
            app.cities{end+1} = newCity; 
            app.SelectCityDropDown.Items = app.cities;
            app.AddcityEditField.Value = '';
        end
        end

        % Button pushed function: GetWheaterButton
        function GetWheaterButtonPushed(app, event)
            app.getWheathersData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SelectCityDropDownLabel = uilabel(app.UIFigure);
            app.SelectCityDropDownLabel.Text = 'Select City:';
            app.SelectCityDropDown = uidropdown(app.UIFigure);
            app.AddcityLabel = uilabel(app.UIFigure);
            app.AddcityLabel.Text = 'Add city:';
            app.AddcityEditField = uieditfield(app.UIFigure, 'text');
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.GetWheaterButton = uibutton(app.UIFigure, 'push');
            app.GetWheaterButton.ButtonPushedFcn = createCallbackFcn(app, @GetWheaterButtonPushed, true);
            app.GetWheaterButton.Text = 'Get Wheater';
            app.CurrentInformationsLabel = uilabel(app.UIFigure);
            app.CurrentInformationsLabel.Text = 'Current Informations';
            app.LastFiveDaysLabel = uilabel(app.UIFigure);
            app.LastFiveDaysLabel.Text = 'Last Five Days';
            app.TemperatureCLabel = uilabel(app.UIFigure);
            app.TemperatureCLabel.Text = 'Temperature(°C):';
            app.HumidityLabel = uilabel(app.UIFigure);
            app.HumidityLabel.Text = 'Humidity:';
            app.WindSpeedLabel = uilabel(app.UIFigure);
            app.WindSpeedLabel.Text = 'Wind Speed:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wheathers

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end